classdef lemon_battery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        mainscreenTab              matlab.ui.container.Tab
        CurrentmiliampsGaugeLabel  matlab.ui.control.Label
        CurrentmiliampsGauge       matlab.ui.control.Gauge
        VoltageVoltsGaugeLabel     matlab.ui.control.Label
        VoltageVoltsGauge          matlab.ui.control.Gauge
        ColorPicker                matlab.ui.control.ColorPicker
        ColorPickerLabel           matlab.ui.control.Label
        InformationPanel           matlab.ui.container.Panel
        GoToQuizButton             matlab.ui.control.Button
        RandomTipFunFactLabel      matlab.ui.control.Label
        nexttipButton              matlab.ui.control.Button
        prevtipButton              matlab.ui.control.Button
        tip_text                   matlab.ui.control.TextArea
        ControlsPanel              matlab.ui.container.Panel
        CircuitTypeButtonGroup     matlab.ui.container.ButtonGroup
        ParallelButton             matlab.ui.control.RadioButton
        SeriesButton               matlab.ui.control.RadioButton
        FruitDropDown              matlab.ui.control.DropDown
        FruitDropDownLabel         matlab.ui.control.Label
        NumberofFruitsSlider       matlab.ui.control.Slider
        NumberofFruitsSliderLabel  matlab.ui.control.Label
        OutputLamp                 matlab.ui.control.Lamp
        OutputLampLabel            matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        quizscreenTab              matlab.ui.container.Tab
        QuizLabel                  matlab.ui.control.Label
        question_text              matlab.ui.control.TextArea
        PrevQuestionButton         matlab.ui.control.Button
        NextQuestionButton         matlab.ui.control.Button
        question_index_label       matlab.ui.control.Label
        AnswerButtonGroup          matlab.ui.container.ButtonGroup
        SubmitButton               matlab.ui.control.Button
        resultsscreenTab           matlab.ui.container.Tab
        ResultsLabel               matlab.ui.control.Label
        LearnSomeMoreButton        matlab.ui.control.Button
        TryAgainButton             matlab.ui.control.Button
        score_disp                 matlab.ui.control.TextArea
    end


    properties (Access = private)
        num_questions int32
        question_index int32  % keeps track of the current question
        questions string % list of the questions
        answers string % list of the options
        answer_buttons matlab.ui.control.RadioButton
        dummy matlab.ui.control.RadioButton
        correct string % list of the correct answers
        scores int32 % how much each question is worth
        selections int32
        fact_timer timer % timer to control the swapping of facts
        fact_index int32
        facts string
        background_color string
        foreground_color string
        bright_background_color string
        bright_foreground_color string
        dark_background_color string

    end

    methods (Access = private)

        % this is just a function that runs periodically to change the 
        % fact in the fun fact box
        function timer_callback(app)
            n_facts = length(app.facts);

            if app.fact_index == n_facts
                app.fact_index = 1;
            else
                app.fact_index = app.fact_index + 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);

        end

        % sets the current question in the quiz
        function set_question(app, index)
            [~,num_answers] = size(app.answers);
            app.question_text.Value = app.questions(index);
            app.question_index = index;

            % this just checks whether we should display the 
            % next/previous buttons depending on what question we're on
            if index > 1 
                app.PrevQuestionButton.Visible = "on";
                app.PrevQuestionButton.Enable="on";
            else
                app.PrevQuestionButton.Visible = "off";
                app.PrevQuestionButton.Enable = "off";
            end

            if index < num_answers 
                app.NextQuestionButton.Visible = "on";
                app.NextQuestionButton.Enable = "on";

            else
                app.NextQuestionButton.Visible = "off";
                app.NextQuestionButton.Enable = "off";
            end

            
            % get rid of the buttons for the previous question
            for i = 1:length(app.answer_buttons)
                delete(app.answer_buttons(i));
            end


            height = app.AnswerButtonGroup.InnerPosition(4)/num_answers;

            % create new buttons for each possible answer
            for i = 1:num_answers
                button = uiradiobutton(app.AnswerButtonGroup);
                button.Text = app.answers(index, i);
                button.FontColor = app.foreground_color;
                button.Position(2) = (height) * (i-1);
                button.Position(3) = app.AnswerButtonGroup.Position(3) - 2;

                % disp(app.answers(index,i));
                % disp(button.Position);

                app.answer_buttons(i) = button;
            end

            % set the counter to display the correct question index
            app.question_index_label.Text = ...
                sprintf("Question %d/%d", index, app.num_questions);

            % check if they've already selected an answer for this
            % question, and if not, unselect all the buttons by selecting
            % the dummy button
            if app.selections(index) == 0 
                app.AnswerButtonGroup.SelectedObject = app.dummy;
            else 
                app.AnswerButtonGroup.SelectedObject = ...
                    app.answer_buttons(app.selections(index));
            end
        end
        
        % just applies the colorscheme to all the components in the app
        function apply_color(app)

            % app.bright_foreground_color = "#ffffff";
            % app.foreground_color = "#ffffff";
            % app.background_color = "#6986b5";
            % app.dark_background_color = "#08080d";
            % app.bright_background_color = "#181825";
            border_color = "#ffffff";
            border_width = 1;
            

            % main tab
            app.mainscreenTab.BackgroundColor = app.background_color;
            app.mainscreenTab.ForegroundColor = app.foreground_color;

            app.ControlsPanel.BackgroundColor = app.background_color;
            app.ControlsPanel.ForegroundColor = app.foreground_color;
            app.ControlsPanel.BorderColor = border_color;
            app.ControlsPanel.BorderWidth = border_width;

            app.NumberofFruitsSliderLabel.FontColor = app.foreground_color;
            app.NumberofFruitsSlider.FontColor = app.foreground_color;
            
            app.InformationPanel.BackgroundColor = app.background_color;
            app.InformationPanel.ForegroundColor = app.foreground_color;
            app.InformationPanel.BorderColor = border_color;
            app.InformationPanel.BorderWidth = border_width;

            app.RandomTipFunFactLabel.FontColor = app.foreground_color;
           

            app.FruitDropDown.BackgroundColor = app.background_color;
            app.FruitDropDown.FontColor = app.foreground_color;
            app.CircuitTypeButtonGroup.BackgroundColor = app.background_color;
            app.CircuitTypeButtonGroup.ForegroundColor = app.foreground_color;


            app.FruitDropDownLabel.BackgroundColor = app.background_color;
            app.FruitDropDownLabel.FontColor=app.foreground_color;

            app.tip_text.BackgroundColor = app.bright_background_color;
            app.tip_text.FontColor = app.bright_foreground_color;
            
            app.OutputLampLabel.BackgroundColor = app.background_color;
            app.OutputLampLabel.FontColor = app.foreground_color;

            app.VoltageVoltsGaugeLabel.BackgroundColor = app.background_color;
            app.VoltageVoltsGaugeLabel.FontColor = app.foreground_color;
            app.CurrentmiliampsGaugeLabel.BackgroundColor = app.background_color;
            app.CurrentmiliampsGaugeLabel.FontColor=app.foreground_color;

            app.VoltageVoltsGauge.BackgroundColor = app.background_color;
            app.VoltageVoltsGauge.FontColor = app.foreground_color;

            app.CurrentmiliampsGauge.BackgroundColor = app.background_color;
            app.CurrentmiliampsGauge.FontColor = app.foreground_color;

            app.prevtipButton.BackgroundColor = app.dark_background_color;
            app.prevtipButton.FontColor = app.bright_foreground_color;

            app.nexttipButton.BackgroundColor = app.dark_background_color;
            app.nexttipButton.FontColor = app.bright_foreground_color;

            app.GoToQuizButton.BackgroundColor = app.dark_background_color;
            app.GoToQuizButton.FontColor = app.bright_foreground_color;


            % quiz tab
            app.quizscreenTab.BackgroundColor = app.background_color;
            app.QuizLabel.BackgroundColor = app.background_color;
            app.QuizLabel.FontColor = app.bright_foreground_color;
            app.question_index_label.BackgroundColor = app.background_color;
            app.question_index_label.FontColor = app.foreground_color;
            app.question_text.BackgroundColor = app.bright_background_color;
            app.question_text.FontColor = app.bright_foreground_color;

            app.AnswerButtonGroup.BackgroundColor = app.bright_background_color;
            app.AnswerButtonGroup.ForegroundColor = app.foreground_color;


            app.PrevQuestionButton.BackgroundColor = app.dark_background_color;
        
            app.PrevQuestionButton.FontColor = app.bright_foreground_color;

            app.NextQuestionButton.BackgroundColor = app.dark_background_color;
            app.NextQuestionButton.FontColor = app.bright_foreground_color;

            app.SubmitButton.BackgroundColor = app.dark_background_color;
            app.SubmitButton.FontColor = app.bright_foreground_color;


            % results tab
            app.resultsscreenTab.BackgroundColor = app.background_color;
            app.ResultsLabel.BackgroundColor = app.background_color;
            app.ResultsLabel.FontColor = app.bright_foreground_color;
            app.score_disp.BackgroundColor = app.bright_background_color;
            app.score_disp.FontColor = app.bright_foreground_color;

            app.TryAgainButton.BackgroundColor = app.dark_background_color;
            app.TryAgainButton.FontColor = app.bright_foreground_color;
               
            app.LearnSomeMoreButton.BackgroundColor = app.dark_background_color;
            app.LearnSomeMoreButton.FontColor = app.bright_foreground_color;
            

        end
        
        function update_voltage(app, descrete)
            index = app.FruitDropDown.ValueIndex;
            voltage_amounts = [0.7, 0.9, 0.5, 0.6];
            current_amounts = [0.001, 0.001,0.0001,0.00005];

            source = voltage_amounts(index);
            current = current_amounts(index);
            count = app.NumberofFruitsSlider.Value;

            if app.ParallelButton.Value == 1
                voltage = source;
                amps = current * count;

              
            else
                voltage = source * count;
                amps = current;
            end

            if descrete == 1
                app.VoltageVoltsGauge.Value = voltage;

                % current is in mili amps
                app.CurrentmiliampsGauge.Value = amps * 10e2;
            
            else
                old_voltage = app.VoltageVoltsGauge.Value;
                old_current = app.CurrentmiliampsGauge.Value;
                steps = 20;
                voltage_temps = linspace(old_voltage, voltage,steps);
                current_temps = linspace(old_current, amps * 10e2, steps);

                for i = 1:steps
                    app.VoltageVoltsGauge.Value = voltage_temps(i);
                    app.CurrentmiliampsGauge.Value=current_temps(i);
                    drawnow;
                end
            end
            % app.VoltageVoltsGauge.Value = voltage;
            
            % current is in mili amps

            % app.CurrentmiliampsGauge.Value = amps * 10e2;

            % brightness is proportional to power, minimum brightness offset 
            brightness_scalar = voltage * amps / 0.009 + 0.1; 
            color_vector = [25 / 255, 255/255, 97 / 255].* ...
                min(brightness_scalar,1.0);

            app.OutputLamp.Color = color_vector;

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.bright_foreground_color = "#ffffff";
            % app.bright_foreground_color = "#5b5e5e";
            % app.foreground_color = "#cdd6f4";
            app.foreground_color = "#f0f0f0";
            % app.background_color = "#11111b";
            app.background_color = "#3c9fba";
            % app.dark_background_color = "#08080d";
            app.dark_background_color = "#346573";
            % app.bright_background_color = "#181825";
            app.bright_background_color = "#5c7db6";

            % this assigns all the colors for the different components, and
            % I feel like there should be a better way to do this, but I
            % don't know.
            % but essentially the scheme has five colors,
            % normal foreground and background colors
            % bright foreground and background colors
            % and a dark background color
            % most components use the regular foreground and background
            % colors for their background and foreground/font colors,
            % however, title labels use the bright foreground color, text
            % boxes use both bright colors, and buttons use the dark
            % background and bright foreground.


            % main tab
            % app.mainscreenTab.BackgroundColor = app.background_color;
            % app.mainscreenTab.ForegroundColor = app.foreground_color;
            % 
            % app.ControlsPanel.BackgroundColor = app.background_color;
            % app.ControlsPanel.ForegroundColor = app.foreground_color;
            % app.ControlsPanel.BorderColor = border_color;
            % app.ControlsPanel.BorderWidth = border_width;
            % 
            % app.NumberofFruitsSliderLabel.FontColor = app.foreground_color;
            % app.NumberofFruitsSlider.FontColor = app.foreground_color;
            % 
            % app.InformationPanel.BackgroundColor = app.background_color;
            % app.InformationPanel.ForegroundColor = app.foreground_color;
            % app.InformationPanel.BorderColor = border_color;
            % app.InformationPanel.BorderWidth = border_width;
            % 
            % app.RandomTipFunFactLabel.FontColor = app.foreground_color;
            % 
            % 
            % app.FruitDropDown.BackgroundColor = app.background_color;
            % app.FruitDropDown.FontColor = app.foreground_color;
            % 
            % 
            % app.FruitDropDownLabel.BackgroundColor = app.background_color;
            % app.FruitDropDownLabel.FontColor=app.foreground_color;
            % 
            % app.tip_text.BackgroundColor = app.bright_background_color;
            % app.tip_text.FontColor = app.bright_foreground_color;
            % 
            % app.OutputLampLabel.BackgroundColor = app.background_color;
            % app.OutputLampLabel.FontColor = app.foreground_color;
            % 
            % app.VoltageGaugeLabel.BackgroundColor = app.background_color;
            % app.VoltageGaugeLabel.FontColor = app.foreground_color;
            % 
            % app.VoltageVoltsGauge.BackgroundColor = app.background_color;
            % app.VoltageVoltsGauge.FontColor = app.foreground_color;
            % 
            % app.prevtipButton.BackgroundColor = app.dark_background_color;
            % app.prevtipButton.FontColor = app.bright_foreground_color;
            % 
            % app.nexttipButton.BackgroundColor = app.dark_background_color;
            % app.nexttipButton.FontColor = app.bright_foreground_color;
            % 
            % app.GoToQuizButton.BackgroundColor = app.dark_background_color;
            % app.GoToQuizButton.FontColor = app.bright_foreground_color;
            % 
            % 
            % % quiz tab
            % app.quizscreenTab.BackgroundColor = app.background_color;
            % app.QuizLabel.BackgroundColor = app.background_color;
            % app.QuizLabel.FontColor = app.bright_foreground_color;
            % app.question_index_label.BackgroundColor = app.background_color;
            % app.question_index_label.FontColor = app.foreground_color;
            % app.question_text.BackgroundColor = app.bright_background_color;
            % app.question_text.FontColor = app.bright_foreground_color;
            % 
            % app.AnswerButtonGroup.BackgroundColor = app.bright_background_color;
            % app.AnswerButtonGroup.ForegroundColor = app.foreground_color;
            % 
            % 
            % app.PrevQuestionButton.BackgroundColor = app.dark_background_color;
            % 
            % app.PrevQuestionButton.FontColor = app.bright_foreground_color;
            % 
            % app.NextQuestionButton.BackgroundColor = app.dark_background_color;
            % app.NextQuestionButton.FontColor = app.bright_foreground_color;
            % 
            % app.SubmitButton.BackgroundColor = app.dark_background_color;
            % app.SubmitButton.FontColor = app.bright_foreground_color;
            % 
            % 
            % 
            % 
            % 
            % % results tab
            % app.resultsscreenTab.BackgroundColor = app.background_color;
            % app.ResultsLabel.BackgroundColor = app.background_color;
            % app.ResultsLabel.FontColor = app.bright_foreground_color;
            % app.score_disp.BackgroundColor = app.bright_background_color;
            % app.score_disp.FontColor = app.bright_foreground_color;
            % 
            % app.TryAgainButton.BackgroundColor = app.dark_background_color;
            % app.TryAgainButton.FontColor = app.bright_foreground_color;
            % 
            % app.LearnSomeMoreButton.BackgroundColor = app.dark_background_color;
            % app.LearnSomeMoreButton.FontColor = app.bright_foreground_color;
            % 

            app.apply_color();
            app.update_voltage(0);


            % load in the facts datafile and start the shuffle timer
            app.fact_index = 1;
            app.facts = load('facts.mat').facts;
            app.fact_timer = timer;
            app.fact_timer.TimerFcn = @(varargin)app.timer_callback;
            app.fact_timer.ExecutionMode = "fixedRate";
            app.fact_timer.Period=10;
            start(app.fact_timer);

            % quiz data loading
            app.questions = load("quiz.mat").questions;
            app.answers = load("quiz.mat").answers;
            correct_indices = load("quiz.mat").correct;
            app.scores = load("quiz.mat").scores;
            [~,num_answers] = size(app.answers);

            % store the correct answers for the questions, and then shuffle
            % the answer vector
            counter = 1;
            for index = correct_indices
                app.correct(counter) = app.answers(counter, index);
                app.answers(counter,:) = ...
                    app.answers(counter, randperm(num_answers));
                counter = counter + 1;
            end


            % set up the dummy button
            app.dummy = uiradiobutton(app.AnswerButtonGroup);
            app.dummy.Position(3) = 0;
            app.dummy.Position(4) = 0;
            app.dummy.Visible = 'off';


            % load the first question
            app.question_index=1;
            app.num_questions = length(app.questions);
            app.selections = zeros(app.num_questions);
            app.set_question(1);
        end

        % Button pushed function: NextQuestionButton
        function NextQuestionButtonPushed(app, event)
            % quick bounds check
            if app.question_index < app.num_questions
                app.set_question(app.question_index + 1);
            end
        end

        % Button pushed function: prevtipButton
        function prevtipButtonPushed(app, event)
            n_facts = length(app.facts);

            if app.fact_index == 1
                app.fact_index =n_facts;
            else
                app.fact_index = app.fact_index - 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);
        end

        % Button pushed function: nexttipButton
        function nexttipButtonPushed(app, event)
            app.timer_callback();
        end

        % Value changed function: FruitDropDown
        function FruitDropDownValueChanged(app, event)
            value = app.FruitDropDown.Value;


            value = replace(value, " ", "");
            value = lower(value);

            path = append(value, ".png");

            app.Image.ImageSource = path;


            app.update_voltage(0);
        end

        % Button pushed function: GoToQuizButton
        function GoToQuizButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.quizscreenTab;
            app.set_question(1);
        end

        % Button pushed function: PrevQuestionButton
        function PrevQuestionButtonPushed(app, event)
            % quick bounds check
            if app.question_index > 1
                app.set_question(app.question_index - 1);
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           

            max_score = sum(app.scores);
            score = 0;

            % check if the value is correct
            for i = 1:app.num_questions 
                if app.selections(i) ~= 0 
                    if app.answers(i,app.selections(i)) == app.correct(i)
                        score =score+ app.scores(i);
                    end
                end
            end

            % display the results
            app.TabGroup.SelectedTab = app.resultsscreenTab;
            percent = double(score) / double(max_score) * 100.0;
            app.score_disp.Value = ...
                sprintf(...
                "You scored: %d/%d \nPercent: %0.1f%s", ...
                score, ...
                max_score, ...
                percent, ...
                "%");
        end

        % Button pushed function: TryAgainButton
        function TryAgainButtonPushed(app, event)
            % go back to the quiz
            app.TabGroup.SelectedTab = app.quizscreenTab;
            app.set_question(1);
        end

        % Button pushed function: LearnSomeMoreButton
        function LearnSomeMoreButtonPushed(app, event)
            % go back to the main screen
            app.TabGroup.SelectedTab = app.mainscreenTab;
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
            value = app.ColorPicker.Value;

            app.background_color = rgb2hex(value);
            app.apply_color();
            
        end

        % Selection changed function: AnswerButtonGroup
        function AnswerButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnswerButtonGroup.SelectedObject;
            index = find(app.answer_buttons == selectedButton);
           

            app.selections(app.question_index) = index;
            disp(index);
            disp(app.correct(app.question_index));
            disp(app.answers(app.question_index, :));
        end

        % Value changed function: NumberofFruitsSlider
        function NumberofFruitsSliderValueChanged(app, event)
            app.update_voltage(0);
            
        end

        % Selection changed function: CircuitTypeButtonGroup
        function CircuitTypeButtonGroupSelectionChanged(app, event)
            app.update_voltage(0);
            
        end

        % Value changing function: NumberofFruitsSlider
        function NumberofFruitsSliderValueChanging(app, event)
           app.NumberofFruitsSlider.Value = event.Value;
           app.update_voltage(1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.mainscreenTab = uitab(app.TabGroup);
            app.mainscreenTab.Title = 'main screen';
            app.Image = uiimage(app.mainscreenTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'lemon.png');
            app.OutputLampLabel = uilabel(app.mainscreenTab);
            app.OutputLampLabel.Text = 'Output';
            app.OutputLamp = uilamp(app.mainscreenTab);
            app.ControlsPanel = uipanel(app.mainscreenTab);
            app.ControlsPanel.Title = 'Controls';
            app.NumberofFruitsSliderLabel = uilabel(app.ControlsPanel);
            app.NumberofFruitsSliderLabel.Text = 'Number of Fruits';
            app.NumberofFruitsSlider = uislider(app.ControlsPanel);
            app.NumberofFruitsSlider.Limits = [1 10];
            app.NumberofFruitsSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofFruitsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofFruitsSliderValueChanged, true);
            app.NumberofFruitsSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofFruitsSliderValueChanging, true);
            app.NumberofFruitsSlider.Value = 1;
            app.FruitDropDownLabel = uilabel(app.ControlsPanel);
            app.FruitDropDownLabel.Text = 'Fruit';
            app.FruitDropDown = uidropdown(app.ControlsPanel);
            app.FruitDropDown.Items = {'Lemon', 'Lime', 'Grapefruit', 'Orange'};
            app.FruitDropDown.ValueChangedFcn = createCallbackFcn(app, @FruitDropDownValueChanged, true);
            app.FruitDropDown.Value = 'Lemon';
            app.CircuitTypeButtonGroup = uibuttongroup(app.ControlsPanel);
            app.CircuitTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CircuitTypeButtonGroupSelectionChanged, true);
            app.CircuitTypeButtonGroup.Title = 'Circuit Type';
            app.SeriesButton = uiradiobutton(app.CircuitTypeButtonGroup);
            app.SeriesButton.Text = 'Series';
            app.SeriesButton.Value = true;
            app.ParallelButton = uiradiobutton(app.CircuitTypeButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.InformationPanel = uipanel(app.mainscreenTab);
            app.InformationPanel.Title = 'Information';
            app.tip_text = uitextarea(app.InformationPanel);
            app.tip_text.Editable = 'off';
            app.tip_text.Value = {'you can get different voltages by changing the type of fruit, as well as the ripeness, etc.'};
            app.prevtipButton = uibutton(app.InformationPanel, 'push');
            app.prevtipButton.ButtonPushedFcn = createCallbackFcn(app, @prevtipButtonPushed, true);
            app.prevtipButton.Text = '←     prev tip';
            app.nexttipButton = uibutton(app.InformationPanel, 'push');
            app.nexttipButton.ButtonPushedFcn = createCallbackFcn(app, @nexttipButtonPushed, true);
            app.nexttipButton.Text = 'next tip     →';
            app.RandomTipFunFactLabel = uilabel(app.InformationPanel);
            app.RandomTipFunFactLabel.Text = 'Random Tip / Fun Fact';
            app.GoToQuizButton = uibutton(app.InformationPanel, 'push');
            app.GoToQuizButton.ButtonPushedFcn = createCallbackFcn(app, @GoToQuizButtonPushed, true);
            app.GoToQuizButton.Text = 'Go To Quiz';
            app.ColorPickerLabel = uilabel(app.mainscreenTab);
            app.ColorPickerLabel.Enable = 'off';
            app.ColorPickerLabel.Visible = 'off';
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.mainscreenTab);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.ColorPicker.Enable = 'off';
            app.ColorPicker.Visible = 'off';
            app.VoltageVoltsGauge = uigauge(app.mainscreenTab, 'circular');
            app.VoltageVoltsGauge.Limits = [0 10];
            app.VoltageVoltsGaugeLabel = uilabel(app.mainscreenTab);
            app.VoltageVoltsGaugeLabel.Text = {'Voltage'; '(Volts)'};
            app.CurrentmiliampsGauge = uigauge(app.mainscreenTab, 'circular');
            app.CurrentmiliampsGauge.Limits = [0 10];
            app.CurrentmiliampsGaugeLabel = uilabel(app.mainscreenTab);
            app.CurrentmiliampsGaugeLabel.Text = {'Current'; '(miliamps)'};
            app.quizscreenTab = uitab(app.TabGroup);
            app.quizscreenTab.Title = 'quiz screen';
            app.SubmitButton = uibutton(app.quizscreenTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.AnswerButtonGroup = uibuttongroup(app.quizscreenTab);
            app.AnswerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroupSelectionChanged, true);
            app.AnswerButtonGroup.Title = 'Answer';
            app.question_index_label = uilabel(app.quizscreenTab);
            app.question_index_label.Text = 'Question ? of ?';
            app.NextQuestionButton = uibutton(app.quizscreenTab, 'push');
            app.NextQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @NextQuestionButtonPushed, true);
            app.NextQuestionButton.Text = 'Next Question';
            app.PrevQuestionButton = uibutton(app.quizscreenTab, 'push');
            app.PrevQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @PrevQuestionButtonPushed, true);
            app.PrevQuestionButton.Text = 'Prev Question';
            app.question_text = uitextarea(app.quizscreenTab);
            app.question_text.Editable = 'off';
            app.question_text.Value = {'question text'};
            app.QuizLabel = uilabel(app.quizscreenTab);
            app.QuizLabel.Text = 'Quiz';
            app.resultsscreenTab = uitab(app.TabGroup);
            app.resultsscreenTab.Title = 'results screen';
            app.score_disp = uitextarea(app.resultsscreenTab);
            app.score_disp.Editable = 'off';
            app.score_disp.Value = {'you scored: ?/?!'; 'Percent: 100.0%'};
            app.TryAgainButton = uibutton(app.resultsscreenTab, 'push');
            app.TryAgainButton.ButtonPushedFcn = createCallbackFcn(app, @TryAgainButtonPushed, true);
            app.TryAgainButton.Text = 'Try Again';
            app.LearnSomeMoreButton = uibutton(app.resultsscreenTab, 'push');
            app.LearnSomeMoreButton.ButtonPushedFcn = createCallbackFcn(app, @LearnSomeMoreButtonPushed, true);
            app.LearnSomeMoreButton.Text = 'Learn Some More';
            app.ResultsLabel = uilabel(app.resultsscreenTab);
            app.ResultsLabel.Text = 'Results!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lemon_battery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end